classdef sirmodel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InfectedSliderLabel      matlab.ui.control.Label
        InfectedSlider           matlab.ui.control.Slider
        InfectedNum              matlab.ui.control.NumericEditField
        ContactRateSliderLabel   matlab.ui.control.Label
        ContactRateSlider        matlab.ui.control.Slider
        ContactRateNum           matlab.ui.control.NumericEditField
        RecoveryRateNum          matlab.ui.control.NumericEditField
        RecoveryRateSliderLabel  matlab.ui.control.Label
        RecoveryRateSlider       matlab.ui.control.Slider
        UIAxes_susceptible       matlab.ui.control.UIAxes
        UIAxes_infected          matlab.ui.control.UIAxes
        UIAxes_removed           matlab.ui.control.UIAxes
        MaxTimeSliderLabel       matlab.ui.control.Label
        MaxTimeSlider            matlab.ui.control.Slider
        MaxTimeNum               matlab.ui.control.NumericEditField
        R_effLabel               matlab.ui.control.Label
        reffNum                  matlab.ui.control.NumericEditField
        RemovedSliderLabel       matlab.ui.control.Label
        RemovedSlider            matlab.ui.control.Slider
        RemovedNum               matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ContactRateNum, ContactRateSlider, 
        % InfectedNum, InfectedSlider, MaxTimeNum, MaxTimeSlider, 
        % MaxTimeSlider, RecoveryRateNum, RecoveryRateSlider, 
        % RemovedNum, RemovedSlider
        function Reload(app, event)
            % Load default values for each numeric Edit Field
            app.InfectedNum.Value = app.InfectedSlider.Value;
            app.ContactRateNum.Value = app.ContactRateSlider.Value;
            app.RecoveryRateNum.Value = app.RecoveryRateSlider.Value;
            app.MaxTimeNum.Value = app.MaxTimeSlider.Value;
            app.RemovedNum.Value = app.RemovedSlider.Value;
            
            
            % Get contact and recovery rate
            a = app.ContactRateSlider.Value; % Contact rate
            b = app.RecoveryRateSlider.Value; % Recovery rate
            

            f = @(t,x) [-a*x(1)*x(2); a*x(1)*x(2)-b*x(2); b*x(2)];

            i0 = app.InfectedSlider.Value;    % Default starting value: 0.002; assuming 0.2% of the population is infected
            r0 = app.RemovedSlider.Value;     % Default starting value 0
            s0 = 1 - i0 - r0;                 % Default starting value: 0.998;
            
            app.reffNum.Value = (a / b) * (s0 / 1);
            
            if (s0 < 0)
                msgbox('Constraint "s + i + r = 1" not satisfied!', 'Warning', 'error')
            end
            
            tfinal = app.MaxTimeSlider.Value; %standard value = 700

            [t,xa] = ode45(f,[0 tfinal], [s0 i0 r0]);
            
            plot(app.UIAxes_susceptible, t, xa(:,1));
            xlim(app.UIAxes_susceptible, [0 tfinal]);
            ylim(app.UIAxes_susceptible, [0 1]);
            plot(app.UIAxes_infected, t, xa(:,2), 'm');
            xlim(app.UIAxes_infected, [0 tfinal]);
            ylim(app.UIAxes_infected, [0 1]);
            plot(app.UIAxes_removed, t, xa(:,3), 'g');
            xlim(app.UIAxes_removed, [0 tfinal]);
            ylim(app.UIAxes_removed, [0 1]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InfectedSliderLabel = uilabel(app.UIFigure);
            app.InfectedSliderLabel.Text = 'Infected';
            app.InfectedSlider = uislider(app.UIFigure);
            app.InfectedSlider.Limits = [0 1];
            app.InfectedSlider.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.InfectedSlider.Value = 0.001404681;
            app.InfectedNum = uieditfield(app.UIFigure, 'numeric');
            app.InfectedNum.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.InfectedNum.Value = 0.002;
            app.ContactRateSliderLabel = uilabel(app.UIFigure);
            app.ContactRateSliderLabel.Text = 'Contact Rate';
            app.ContactRateSlider = uislider(app.UIFigure);
            app.ContactRateSlider.Limits = [0 1];
            app.ContactRateSlider.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.ContactRateSlider.Value = 0.141865;
            app.ContactRateNum = uieditfield(app.UIFigure, 'numeric');
            app.ContactRateNum.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.ContactRateNum.Value = 0.1;
            app.RecoveryRateNum = uieditfield(app.UIFigure, 'numeric');
            app.RecoveryRateNum.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.RecoveryRateNum.Value = 0.0714;
            app.RecoveryRateSliderLabel = uilabel(app.UIFigure);
            app.RecoveryRateSliderLabel.Text = 'Recovery Rate';
            app.RecoveryRateSlider = uislider(app.UIFigure);
            app.RecoveryRateSlider.Limits = [0 1];
            app.RecoveryRateSlider.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.RecoveryRateSlider.Value = 0.0714;
            app.UIAxes_susceptible = uiaxes(app.UIFigure);
            title(app.UIAxes_susceptible, '')
            xlabel(app.UIAxes_susceptible, 't')
            ylabel(app.UIAxes_susceptible, '% susceptible')
            app.UIAxes_infected = uiaxes(app.UIFigure);
            title(app.UIAxes_infected, '')
            xlabel(app.UIAxes_infected, 't')
            ylabel(app.UIAxes_infected, '% infected')
            app.UIAxes_removed = uiaxes(app.UIFigure);
            title(app.UIAxes_removed, '')
            xlabel(app.UIAxes_removed, 't')
            ylabel(app.UIAxes_removed, '% removed')
            app.MaxTimeSliderLabel = uilabel(app.UIFigure);
            app.MaxTimeSliderLabel.Text = {'MaxTime'; ''};
            app.MaxTimeSlider = uislider(app.UIFigure);
            app.MaxTimeSlider.Limits = [100 700];
            app.MaxTimeSlider.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.MaxTimeSlider.ValueChangingFcn = createCallbackFcn(app, @Reload, true);
            app.MaxTimeSlider.Value = 700;
            app.MaxTimeNum = uieditfield(app.UIFigure, 'numeric');
            app.MaxTimeNum.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.MaxTimeNum.Value = 700;
            app.R_effLabel = uilabel(app.UIFigure);
            app.R_effLabel.Text = 'R_eff';
            app.reffNum = uieditfield(app.UIFigure, 'numeric');
            app.reffNum.Editable = 'off';
            app.RemovedSliderLabel = uilabel(app.UIFigure);
            app.RemovedSliderLabel.Text = 'Removed';
            app.RemovedSlider = uislider(app.UIFigure);
            app.RemovedSlider.Limits = [0 1];
            app.RemovedSlider.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.RemovedSlider.Value = 0.001634174;
            app.RemovedNum = uieditfield(app.UIFigure, 'numeric');
            app.RemovedNum.Limits = [0 1];
            app.RemovedNum.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sirmodel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end