classdef sirmodel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SusceptibleSliderLabel   matlab.ui.control.Label
        SusceptibleSlider        matlab.ui.control.Slider
        InfectedSliderLabel      matlab.ui.control.Label
        InfectedSlider           matlab.ui.control.Slider
        SusceptibleNum           matlab.ui.control.NumericEditField
        InfectedNum              matlab.ui.control.NumericEditField
        ContactRateSliderLabel   matlab.ui.control.Label
        ContactRateSlider        matlab.ui.control.Slider
        ContactRateNum           matlab.ui.control.NumericEditField
        RecoveryRateNum          matlab.ui.control.NumericEditField
        RecoveryRateSliderLabel  matlab.ui.control.Label
        RecoveryRateSlider       matlab.ui.control.Slider
        UIAxes_susceptible       matlab.ui.control.UIAxes
        UIAxes_infected          matlab.ui.control.UIAxes
        UIAxes_recovered         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ContactRateSlider, InfectedSlider, 
        % RecoveryRateSlider, SusceptibleSlider, SusceptibleSlider
        function Reload(app, event)
            % Load default values for each numeric Edit Field
            app.SusceptibleNum.Value = app.SusceptibleSlider.Value;
            app.InfectedNum.Value = app.InfectedSlider.Value;
            app.ContactRateNum.Value = app.ContactRateSlider.Value;
            app.RecoveryRateNum.Value = app.RecoveryRateSlider.Value;
            
            % Get contact and recovery rate
            a = app.ContactRateSlider.Value; % Contact rate
            b = app.RecoveryRateSlider.Value; % Recovery rate

            f = @(t,x) [-a*x(1)*x(2); a*x(1)*x(2)-b*x(2); b*x(2)];

            s0 = app.SusceptibleSlider.Value; % Default starting value: 0.998;
            i0 = app.InfectedSlider.Value;    % Default starting value: 0.002; assuming 0.2% of the population is infected
            r0 = 0;
            
            tfinal = 700;

            [t,xa] = ode45(f,[0 tfinal], [s0 i0 r0]);
            
            plot(app.UIAxes_susceptible, t, xa(:,1))
            plot(app.UIAxes_infected, t, xa(:,2), 'm')
            plot(app.UIAxes_recovered, t, xa(:,3), 'g')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SusceptibleSliderLabel = uilabel(app.UIFigure);
            app.SusceptibleSliderLabel.Text = 'Susceptible';
            app.SusceptibleSlider = uislider(app.UIFigure);
            app.SusceptibleSlider.Limits = [0 1];
            app.SusceptibleSlider.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.SusceptibleSlider.ValueChangingFcn = createCallbackFcn(app, @Reload, true);
            app.SusceptibleSlider.Value = 0.998;
            app.InfectedSliderLabel = uilabel(app.UIFigure);
            app.InfectedSliderLabel.Text = 'Infected';
            app.InfectedSlider = uislider(app.UIFigure);
            app.InfectedSlider.Limits = [0 1];
            app.InfectedSlider.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.InfectedSlider.Value = 0.02;
            app.SusceptibleNum = uieditfield(app.UIFigure, 'numeric');
            app.SusceptibleNum.Value = 0.998;
            app.InfectedNum = uieditfield(app.UIFigure, 'numeric');
            app.InfectedNum.Value = 0.002;
            app.ContactRateSliderLabel = uilabel(app.UIFigure);
            app.ContactRateSliderLabel.Text = 'Contact Rate';
            app.ContactRateSlider = uislider(app.UIFigure);
            app.ContactRateSlider.Limits = [0 1];
            app.ContactRateSlider.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.ContactRateSlider.Value = 0.1;
            app.ContactRateNum = uieditfield(app.UIFigure, 'numeric');
            app.ContactRateNum.Value = 0.1;
            app.RecoveryRateNum = uieditfield(app.UIFigure, 'numeric');
            app.RecoveryRateNum.Value = 0.0714;
            app.RecoveryRateSliderLabel = uilabel(app.UIFigure);
            app.RecoveryRateSliderLabel.Text = 'Recovery Rate';
            app.RecoveryRateSlider = uislider(app.UIFigure);
            app.RecoveryRateSlider.Limits = [0 1];
            app.RecoveryRateSlider.ValueChangedFcn = createCallbackFcn(app, @Reload, true);
            app.RecoveryRateSlider.Value = 0.0714;
            app.UIAxes_susceptible = uiaxes(app.UIFigure);
            title(app.UIAxes_susceptible, '')
            xlabel(app.UIAxes_susceptible, 't')
            ylabel(app.UIAxes_susceptible, '% susceptible')
            app.UIAxes_infected = uiaxes(app.UIFigure);
            title(app.UIAxes_infected, '')
            xlabel(app.UIAxes_infected, 't')
            ylabel(app.UIAxes_infected, '% infected')
            app.UIAxes_recovered = uiaxes(app.UIFigure);
            title(app.UIAxes_recovered, '')
            xlabel(app.UIAxes_recovered, 't')
            ylabel(app.UIAxes_recovered, '% recovered')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sirmodel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end